classdef icalPR650 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        ActionsMenu                  matlab.ui.container.Menu
        InitializeMenu               matlab.ui.container.Menu
        MeasureMenu                  matlab.ui.container.Menu
        PlotMenu                     matlab.ui.container.Menu
        RemotemodeMenu               matlab.ui.container.Menu
        LocalmodeMenu                matlab.ui.container.Menu
        FlywheelMenu                 matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutFlywheelMenu            matlab.ui.container.Menu
        AboutPTBMenu                 matlab.ui.container.Menu
        AperturesizeDropDown         matlab.ui.control.DropDown
        AperturesizeDropDownLabel    matlab.ui.control.Label
        ReadyLamp                    matlab.ui.control.Lamp
        ReadyLampLabel               matlab.ui.control.Label
        SpectroradiometerPR650Panel  matlab.ui.container.Panel
        RepeatsSpinner               matlab.ui.control.Spinner
        RepeatsLabel                 matlab.ui.control.Label
        InitializeButton             matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        MeasureSaveButton            matlab.ui.control.Button
        icalPR650Label               matlab.ui.control.Label
        FileInformationPanel         matlab.ui.container.Panel
        DirectoryEditField           matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        CommentTextArea              matlab.ui.control.TextArea
        CommentTextAreaLabel         matlab.ui.control.Label
        spdGraph                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pr650; % Serial port to the pr650
    end
    
    methods (Access = private)
        
        
        function icalPlot(app,varargin)
            % Plot a file from the current selected directory.
            p = inputParser;
            p.addRequired('app',@(x)(isa(x,'icalPR650')));
            p.addParameter('file',true,@islogical);
            p.parse(app,varargin{:});
            
            thisDir  = app.DirectoryEditField.Value;
            
            if p.Results.file
                chdir(thisDir);
                fileInfo = dir('*.mat');
                fileNames = cell(numel(fileInfo),1);
                for ii = 1:numel(fileInfo)
                    fileNames{ii} = fileInfo(ii).name;
                end
                idx = listdlg('ListString',fileNames,'PromptString','Select spd file');
                if isempty(idx)
                    disp('User canceled');
                    return;
                else
                    fileNames = fileNames(idx);
                end
                
                load(fileNames{1},'wavelength');
                spd.energy = zeros(numel(wavelength),numel(idx));
                spd.wave = wavelength;
                app.CommentTextArea.Value = '';
                for ii=1:numel(idx)
                    [spd.energy(:,ii),spd.wave,comment] = ieReadSpectra(fileNames{ii},wavelength);
                    app.CommentTextArea.Value = vertcat(app.CommentTextArea.Value,fileNames{ii});
                    app.CommentTextArea.Value = vertcat(app.CommentTextArea.Value,comment);
                    app.CommentTextArea.Value = vertcat(app.CommentTextArea.Value,'------');
                end
                [~,n,e] = fileparts(fileNames{1});
                fname = [n,e];
            else
                % Current user data
                spd      = app.spdGraph.UserData;
                fname    = app.FilenameEditField.Value;
                fileNames{1} = fullfile(thisDir,fname);
            end
            
            % Do the plot
            plot(app.spdGraph,spd.wave,spd.energy);
            if numel(fileNames) > 1
                legendNames = cell(size(fileNames));
                for ii = 1:numel(fileNames)
                    tmp = strrep(fileNames{ii},'_','\_');
                    legendNames{ii} = tmp;
                end
                legend(app.spdGraph,legendNames{:});
            else
                app.spdGraph.Title.String = sprintf('%s',fname);
            end
            
            grid(app.spdGraph,'on');
            
            % Place the comment in the comment box
            [~,n,e] = fileparts(fileNames{1});
            app.DirectoryEditField.Value = thisDir;
            app.FilenameEditField.Value = [n,e];
        end
        
        function fullName = icalSave(app,varargin)
            % Save the data from the spd in a txt or mat file.
            p = inputParser;
            p.addRequired('app');
            vFunc = @(x)(ismember(x,{'txt','json','mat','csv'}));
            p.addParameter('extension','mat',vFunc);
            p.addParameter('fullname','',@ischar);
            
            p.parse(app,varargin{:});
            fullName = p.Results.fullname;
            ext = p.Results.extension;
            
            if isempty(fullName)
                % If there is only 1 repeat, we use the default name.
                fullName = icalFilename(app,'extension',ext);
            end
            
            % Get the data.
            spd = app.spdGraph.UserData;
            %!%HY debug
            disp(class(spd))
            disp(spd)
            %!%
            comment = app.CommentTextArea.Value;
            if exist(fullName,'file')
                % Give user the chance to rename the file or overwrite
                answer = questdlg('Overwrite?','File save','Yes','No','No');
                
                if strcmp('No',answer)
                    prompt = {'File name'}; dTitle = 'New file name';
                    curName = app.FilenameEditField.Value;
                    newName = sprintf('%s-XXX',curName);
                    dims = [1 35]; defName = {newName};
                    str = inputdlg(prompt, dTitle,dims,defName);
                    if isempty(str), disp('User canceled'); return; end
                    
                    % Update the window.
                    fname = str{1};
                    directory = app.DirectoryEditField.Value;
                    fullName = fullfile(directory,fname);
                    [p,n,e] = fileparts(fullName);
                    fname = [n,'.',ext];
                    if ~isequal(e,ext)
                        fullName = fullfile(p,fname);
                    end
                    app.FilenameEditField.Value = fname;
                    
                end
            else
                switch ext
                    case 'mat'
                        % Save raw data as an ISETCam spectral file
                        ieSaveSpectralFile(spd.wave,spd.energy,comment,fullName);
                    case {'txt','csv'}
                        % Save as CSV text file for Flywheel upload (probably)
                        writematrix([spd.wave(:),spd.energy(:)],fullName);
                    otherwise
                        error('Extension not managed %s\n',ext);
                end
            end
        end
        
        function fullName = icalFilename(app,varargin)
            % Create a default file name with some extension.
            % By default, .mat, extension
            p = inputParser;
            p.addRequired('app');
            vFunc = @(x)(ismember(x,{'txt','json','mat','csv'}));
            p.addParameter('extension','mat',vFunc);
            p.addParameter('new',false,@islogical);
            p.parse(app,varargin{:});
            ext = p.Results.extension;
            forceNew = p.Results.new;
            
            directory = app.DirectoryEditField.Value;
            if isempty(directory)
                % By default the directory is in local and with the date.
                tmp = datestr(now,'yyyy-mm-dd');
                directory = fullfile(icalRootPath,'local',tmp);
                if ~exist(directory,'dir'), mkdir(directory); end
                app.DirectoryEditField.Value = directory;
            end
            
            fname = app.FilenameEditField.Value;
            if isempty(fname) || forceNew
                tmp = datestr(now,'yyyy-mm-dd-HH-MM-SS');
                fname = sprintf('spd-%s',tmp);
                app.FilenameEditField.Value = fname;
            end
            
            fullName = fullfile(directory,fname);
            [p,n,e] = fileparts(fullName);
            
            if ~isequal(e,ext)
                fullName = fullfile(p,[n,'.',ext]);
            end
        end
        
    end
    
    methods (Access = public)
        % Maybe some of the functions about should be public?
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % When the app is opened, we do these things.
            app.icalFilename('new',true);
            assignin('base',"icalApp",app);
            
            % Not ready until initialized.
            app.ReadyLampLabel.Text = 'Initialize port';
            app.ReadyLamp.Color = [0.42,0.75,0.92];
        end

        % Button pushed function: MeasureSaveButton
        function btnMeasure(app, event)
            
            nRepeats = app.RepeatsSpinner.Value;
            fprintf('Planning to measure %d times\n',nRepeats);
            for ii=1:nRepeats
                
                app.ReadyLampLabel.Text = 'Measuring';
                app.ReadyLamp.Color = [1 0 0];
                
                icalPR650CMD(app.pr650,'remote');
                spd    = icalPR650CMD(app.pr650,'measure read spd');
                
                app.spdGraph.UserData = spd;
                
                app.ReadyLampLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0 1 0];
                
                if nRepeats == 1
                    % Save it to a file
                    fname = icalSave(app);
                    fprintf('SPD saved in %s\n',fname);
                else
                    fullname = app.icalFilename;
                    [p,n,e] = fileparts(fullname);
                    n = sprintf('%s-R%02d',n,ii);
                    fullname = fullfile(p,[n,e]);
                    icalSave(app,'fullname',fullname);
                end
                
                % Normally, we plot after measuring.  Just use the spd data in
                % the app, do not read a file.
                icalPlot(app,'file',false);
            end
            
        end

        % Button pushed function: InitializeButton
        function btnInitPR670(app, event)
            
            if isa(app.pr650,'internal.Serialport')
                disp('Serial port already opened.')
            else
                % Open the COM port to the PR650
                app.pr650 = icalPR650Init;
                if isempty(app.pr650)
                    disp('Make sure the PR650 is correctly plugged in.')
                    disp('Particularly the gray plug in the PR650.')
                    warning('Error initializing PR650');
                    return;
                else
                    disp('PR650 com port opened.')
                end
            end
            
            % Set the aperture as in the window
            app.apertureSize;
            
            % Clear any stray stuff in the read buffer
            icalPR650CMD(app.pr650,'clear read buffer');
            
            disp('Setting remote mode (controlled by this program)')
            icalPR650CMD(app.pr650,'remote');
            
            % Create a local folder with the date where the measurements
            % will be stored.  Change into that folder.
            dirName = datestr(now,'yyyy-mm-dd');
            folderName = fullfile(icalRootPath, 'local', dirName);
            if ~exist(folderName, 'dir'), mkdir(folderName); end
            cd(folderName);
            
            % Ready for action.
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0 1 0];
            
        end

        % Callback function: PlotButton, PlotMenu
        function btnPlot(app, event)
            icalPlot(app);
        end

        % Callback function
        function fileSave(app, event)
            fname = icalSave(app,'extension','mat');
            fprintf('SPD saved in %s\n',fname);
        end

        % Menu selected function: FlywheelMenu
        function menuActionsFlywheel(app, event)
            % Upload the current spd data to flywheel
            % We will also want a bulk convert and upload of multiple
            % files, perhaps.
            icalSave(app,'extension','csv');
        end

        % Callback function
        function menuFileSaveText(app, event)
            fname = icalSave(app,'extension','csv');
            fprintf('Saved %s\n',fname);
        end

        % Menu selected function: LocalmodeMenu
        function localmode(app, event)
            icalPR650CMD(app.pr650,'local');
        end

        % Menu selected function: ExitMenu
        function exit(app, event)
            % Could do more cleanup on exit.
            if isa(app.pr650,'internal.Serialport')
                try
                    icalPR650CMD(app.pr650,'local');
                catch
                    disp('Serial port appears disconnected already.')
                end
            end
            app.pr650 = [];  % Close the serial port.
            delete(app);     % Delete the app
        end

        % Menu selected function: RemotemodeMenu
        function remotemode(app, event)
            icalPR650CMD(app.pr650,'remote');
        end

        % Value changed function: AperturesizeDropDown
        function apertureSize(app, event)
            value = app.AperturesizeDropDown.Value;
            switch(value)
                case '1 deg'
                    icalPR650CMD(app.pr650,'aperture large');
                case '0.5 deg'
                    icalPR650CMD(app.pr650,'aperture small');
                case '0.125 deg'
                    icalPR650CMD(app.pr650,'aperture really tiny');
                otherwise
                    warning('Unknown pulldown for aperture.')
            end
            
        end

        % Value changed function: RepeatsSpinner
        function spinnerRepeats(app, event)
            %  value = app.RepeatsSpinner.Value;
            %  fprintf("Plan to measuring %d times.\n",value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @exit, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ActionsMenu = uimenu(app.UIFigure);
            app.ActionsMenu.Text = 'Actions';
            app.InitializeMenu = uimenu(app.ActionsMenu);
            app.InitializeMenu.Text = 'Initialize';
            app.MeasureMenu = uimenu(app.ActionsMenu);
            app.MeasureMenu.Text = 'Measure';
            app.PlotMenu = uimenu(app.ActionsMenu);
            app.PlotMenu.MenuSelectedFcn = createCallbackFcn(app, @btnPlot, true);
            app.PlotMenu.Separator = 'on';
            app.PlotMenu.Text = 'Plot';
            app.RemotemodeMenu = uimenu(app.ActionsMenu);
            app.RemotemodeMenu.MenuSelectedFcn = createCallbackFcn(app, @remotemode, true);
            app.RemotemodeMenu.Separator = 'on';
            app.RemotemodeMenu.Text = 'Remote mode';
            app.LocalmodeMenu = uimenu(app.ActionsMenu);
            app.LocalmodeMenu.MenuSelectedFcn = createCallbackFcn(app, @localmode, true);
            app.LocalmodeMenu.Text = 'Local mode';
            app.FlywheelMenu = uimenu(app.ActionsMenu);
            app.FlywheelMenu.MenuSelectedFcn = createCallbackFcn(app, @menuActionsFlywheel, true);
            app.FlywheelMenu.Separator = 'on';
            app.FlywheelMenu.Text = 'Flywheel';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutFlywheelMenu = uimenu(app.HelpMenu);
            app.AboutFlywheelMenu.Text = 'About Flywheel';
            app.AboutPTBMenu = uimenu(app.HelpMenu);
            app.AboutPTBMenu.Text = 'About PTB';
            app.spdGraph = uiaxes(app.UIFigure);
            title(app.spdGraph, 'PR 650 Data')
            xlabel(app.spdGraph, 'Wave (nm)')
            ylabel(app.spdGraph, 'Energy (watts/sr/nm/m^2)')
            app.FileInformationPanel = uipanel(app.UIFigure);
            app.FileInformationPanel.Title = 'File Information';
            app.CommentTextAreaLabel = uilabel(app.FileInformationPanel);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.FileInformationPanel);
            app.FilenameEditFieldLabel = uilabel(app.FileInformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.FileInformationPanel, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.FileInformationPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FileInformationPanel, 'text');
            app.icalPR650Label = uilabel(app.UIFigure);
            app.icalPR650Label.Text = 'icalPR650';
            app.SpectroradiometerPR650Panel = uipanel(app.UIFigure);
            app.SpectroradiometerPR650Panel.Title = 'Spectroradiometer (PR 650)';
            app.MeasureSaveButton = uibutton(app.SpectroradiometerPR650Panel, 'push');
            app.MeasureSaveButton.ButtonPushedFcn = createCallbackFcn(app, @btnMeasure, true);
            app.MeasureSaveButton.Text = 'Measure + Save';
            app.PlotButton = uibutton(app.SpectroradiometerPR650Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @btnPlot, true);
            app.PlotButton.Text = 'Plot';
            app.InitializeButton = uibutton(app.SpectroradiometerPR650Panel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @btnInitPR670, true);
            app.InitializeButton.Text = 'Initialize';
            app.RepeatsLabel = uilabel(app.SpectroradiometerPR650Panel);
            app.RepeatsLabel.Text = 'Repeats ';
            app.RepeatsSpinner = uispinner(app.SpectroradiometerPR650Panel);
            app.RepeatsSpinner.Limits = [1 Inf];
            app.RepeatsSpinner.RoundFractionalValues = 'on';
            app.RepeatsSpinner.ValueChangedFcn = createCallbackFcn(app, @spinnerRepeats, true);
            app.RepeatsSpinner.Value = 1;
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp.Tooltip = {'Status of the PR670'};
            app.AperturesizeDropDownLabel = uilabel(app.UIFigure);
            app.AperturesizeDropDownLabel.Text = 'Aperture size';
            app.AperturesizeDropDown = uidropdown(app.UIFigure);
            app.AperturesizeDropDown.Items = {'1 deg', '0.5 deg', '0.125 deg'};
            app.AperturesizeDropDown.ValueChangedFcn = createCallbackFcn(app, @apertureSize, true);
            app.AperturesizeDropDown.Value = '1 deg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = icalPR650

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end